classdef kaoLightTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalculateServoAnglesButton      matlab.ui.control.Button
        GridWidthmmEditFieldLabel       matlab.ui.control.Label
        GridWidthmmEditField            matlab.ui.control.NumericEditField
        GridHeightmmEditFieldLabel      matlab.ui.control.Label
        GridHeightmmEditField           matlab.ui.control.NumericEditField
        DistancefromSurfacemmEditFieldLabel  matlab.ui.control.Label
        DistancefromSurfacemmEditField  matlab.ui.control.NumericEditField
        TiltSpinnerLabel                matlab.ui.control.Label
        TiltSpinner                     matlab.ui.control.Spinner
        LaserToggleButton               matlab.ui.control.StateButton
        LaserSweepButton                matlab.ui.control.Button
        StartTestButton                 matlab.ui.control.Button
        RepeatsSpinnerLabel             matlab.ui.control.Label
        RepeatsSpinner                  matlab.ui.control.Spinner
        TestRunningLampLabel            matlab.ui.control.Label
        TestRunningLamp                 matlab.ui.control.Lamp
        StopTestButton                  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SaveAngleButton                 matlab.ui.control.Button
        DeleteAngleButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        pan_angles %stored values of the angles for the panning servo
        adjusted_pan_angles % pan angles with a 90 degree shift to compensaste for the defined angles in the model
        tilt_angles %stored values of the angles for the tilting servo
        digital_output = 0 %stores the current digital output value
        stop_test = 0; %indicates if the test should be stopped
        laser_output = 4096; % Digital output of the laser toggle
        test_angles = []; % Tilt angles used in the experiment
        test_tilt_spinner_val = []; % Tilt spinner value for saved angles
        current_angle_selected = -1; % Current angle in the table selected
    end
    
    methods (Access = private)
        
        %% functions to find the correct angles for a desired laser hit location
        %calculate the pan angle
        function [pan, D] = calcPan(app, B, distance_to_wall, commandPosition)
            pan = atand(commandPosition(1,1) / distance_to_wall);
            D = distance_to_wall / cosd(pan) - B;%Distance of Base of Tilt Platform to Wall (mm)
        end
        
        %calculate the tilt of the laser mount to hit a specific point
        function tilt = calcTilt(app, A, C, D, commandPosition)
            E = commandPosition(1, 2) - A; %find the vertical distace (on y) for the laser to hit a grid relative to where the base of the tilt arm is
            if E == A + C %if the laser need to hit parallel to the x-y plane, set the tilt angle to 90 degrees
                tilt = 90; %degrees
            else %if the laser is not parallel to the x-y plane, calculate the tilt angle
                coeffs = [C-D, -2 * E, D + C];
                angleRoots = roots(coeffs);
                tilt = max(2 * atand(angleRoots));  %degrees
            end
        end
        
        function [laserX, laserZ] = testCalc(app, tilt_angles, pan_angles, A, B, C, distance_to_wall)
            laserX = NaN(size(tilt_angles, 1), size(pan_angles, 2));
            laserZ = NaN(size(tilt_angles, 1), size(pan_angles, 2));
            for i = 1 : size(tilt_angles, 1)
                for j = 1 : size(pan_angles, 2)
                    tilt = tilt_angles(i, j);
                    pan = pan_angles(j);
                    [mount, laser] = applyRotation(app, tilt, pan, A, B, C, distance_to_wall);
        
                    laserX(i, j) = laser(1, 2); %keeps trace of where the laser is hitting the target
                    laserZ(i, j) = laser(3, 2); %keeps trace of where the laser is hitting the target
                end
            end
        end
        
        function [mount, laser] = applyRotation(app, tilt, pan, A, B, C, distance_to_wall)
            panAngle = pan; %degrees
            tiltAngle = 180 - tilt; %degrees
            D = distance_to_wall / cosd(pan) - B;%Distance of Base of Tilt Platform to Wall (mm)
        
            %find the length of the laser to the wall
            if tilt == 90
                laserLength = D;
            else
                laserLength = ((D + C / cosd(tilt)) / sind(tilt) - C * tand(tilt));
            end
        
            RL = xRot(app, -90); %set the rotation of the laser relative to the tilt arm to be perpendicular
            RT = xRot(app, tiltAngle); %find the local rotation of the tilt arm around the x axis
            RB = xRot(app, -90); %set the angle of the base of the tilt arm to be perpendicular to the pan arm
            RP = zRot(app, panAngle); %find the local (also global) rotation of the pan arm around the z axis
        
            panPos = [0; 0; A;]; %find the new end point position of the pan arm
            basePos = (RB * RP) \ [0; 0; B]; %find the new, untranslated position of the base of the tilt arm
            basePos = basePos + panPos; %translate the base of the tilt arm to the correct location
            tiltPos = (RT * RB * RP) \ [0; 0; C]; %find the new end point of the tilt arm before it is translated
            tiltPos = tiltPos + basePos; %translate the tilt arm to the correct global location
            laserPos = (RL * RT * RB * RP) \ [0; 0; laserLength]; %find the untranslated position of the laser
            laserPos = laserPos + tiltPos; %translate the laser to the correct, global position
        
            mount = [[0; 0; 0], panPos, basePos, tiltPos]; %combine all of the points of the laser mount into a matrix
            laser = [tiltPos, laserPos]; %combine the start and end points of the laser
        end
        
        %% Rotation Matrices Definitions
        %rotate around x axis
        function mat = xRot(app, theta)
        mat=[1 0 0
            0 cosd(theta) sind(theta)
            0 -sind(theta) cosd(theta)];
        end
        
        %rotate around y axis
        function mat = yRot(app, theta)
        mat=[cosd(theta) 0 -sind(theta)
            0 1 0
            sind(theta) 0 cosd(theta)];
        end
        
        %rotate around z axis
        function mat = zRot(app, theta)
        mat=[cosd(theta) sind(theta) 0
            -sind(theta) cosd(theta) 0
            0 0 1];
        end

        
        function [start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps] = recordData(app, start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps)
            now = xippmex('time'); %get the current sample time
            [foot_data, foot_time_stamp] = xippmex('cont', 10241, (now - start_time) / 30, '1ksps', start_time); %record data on the first SMA analog input between the start and current time
            [laser_data, laser_time_stamp] = xippmex('cont', 10242, (now - start_time) / 30, '1ksps', start_time); %record data on the second SMA analog input between the start and current time
            start_time = now; %move the start time to when the last recording window ended

            foot_tot_data = [foot_tot_data, foot_data]; %add the recorded foot pedal data to the end of the rest of the data
            foot_stamps = [foot_stamps, double(foot_time_stamp) / 30 : (double(foot_time_stamp) / 30 + length(foot_data) - 1)]; %saves the times of the foot pedal samples
            laser_tot_data = [laser_tot_data, laser_data]; %add the recorded laser state data to the end of the rest of the data
            laser_stamps = [laser_stamps, double(laser_time_stamp) / 30 : (double(laser_time_stamp) / 30 + length(laser_data) - 1)]; %saves the times of the samples
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateServoAnglesButton
        function CalculateServoAnglesButtonPushed(app, event)
            gird_width = app.GridWidthmmEditField.Value; %mm
            gird_height = app.GridHeightmmEditField.Value; %mm
            resolution = gird_width / (sqrt(400 / (gird_width / gird_height)) * (gird_width / gird_height)); %mm
            
            %segment lengths for the laser mount
            A = 41.17; %Panning Arm Length (mm)
            B = 24.78; %Base of Tilt Platform Length (mm)
            C = 33.32; %Tilt Arm Length (mm)
            distance_to_wall = app.DistancefromSurfacemmEditField.Value; %Distance of Base of Tilt Platform to Wall (mm)
            
            app.tilt_angles = NaN(round(gird_height/resolution), round(gird_width/resolution));
            app.pan_angles = NaN(1, round(gird_width/resolution));
            app.adjusted_pan_angles = NaN(1, round(gird_width/resolution));
            rowCount = 1;
            colCount = 1;
            for i = gird_height-resolution : -resolution : 0
                for j = (gird_width-resolution) / 2 : -resolution : -(gird_width-resolution) / 2
                    commandPosition = [j, i]; %set the command position
                    [pan, D] = calcPan(app, B, distance_to_wall, commandPosition); %find the pan angle and the distance of the base of the tilt platform to the wall
                    tilt = calcTilt(app, A, C, D, commandPosition); %find tilt angle
    
                    app.tilt_angles(rowCount, colCount) = tilt;
                    if rowCount == 1
                        app.pan_angles(colCount) = pan;
                        app.adjusted_pan_angles(colCount) = pan +90;
                    end
                    colCount = colCount + 1;
                end
                colCount = 1;
                rowCount = rowCount + 1;
            end
    
            [calculated_hits_x, calculated_hits_z] = testCalc(app, app.tilt_angles, app.pan_angles, A, B, C, distance_to_wall);
            
            size_tilt_angles = size(app.tilt_angles);
            app.TiltSpinner.Limits = [1 size_tilt_angles(1)];
            reshaped_tilt_angles = reshape(app.tilt_angles', [1, size_tilt_angles(1) * size_tilt_angles(2)]);
            
            labels = {'Save calculated x hits to a variable named:' ...
                      'Save calculated z hits to variable named:' ...
                      'Save calculated pan angles to a variable named' ...
                      'Save calculated tilt angles to a variable named' ...
                      'Save reshaped tilt angle array to a variable named'}; 
            vars = {'calculated_hits_x','calculated_hits_z', 'pan_angles', 'tilt_angles', 'reshaped_tilt_angles'}; 
            values = {calculated_hits_x, calculated_hits_z, app.adjusted_pan_angles, app.tilt_angles, reshaped_tilt_angles};
            export2wsdlg(labels,vars,values);
        end

        % Value changed function: LaserToggleButton
        function LaserToggleButtonValueChanged(app, event)
            value = app.LaserToggleButton.Value;
            if value == 1
                xippmex('digout', 5, app.digital_output + app.laser_output); %if the toggle button is on, turn on the laser with whatever the current servo postions are
            else
                xippmex('digout', 5, app.digital_output); %if the toggle button is off, turn off the laser with whatever the current servo postions are
            end
        end

        % Value changed function: TiltSpinner
        function TiltSpinnerValueChanged(app, event)
            value = app.TiltSpinner.Value;
            
            if size(app.tilt_angles) == 0
                error('You have not calculated the servo angles yet.')
            else %move the tilt servo to the tilt angle for the center pan angle
                size_tilt_angles = size(app.tilt_angles);
                app.digital_output = round(size_tilt_angles(2) / 2) + (value - 1) * size_tilt_angles(2);
                xippmex('digout', 5, app.digital_output);
            end
        end

        % Button pushed function: LaserSweepButton
        function LaserSweepButtonPushed(app, event)
            if size(app.tilt_angles) == 0
                error('You have not calculated the servo angles yet.')
            else %sweep the laser in the horizontal direction along the current tilt angle
                size_tilt_angles = size(app.tilt_angles);
               for i = 1:length(app.pan_angles)
                   app.digital_output = i + (app.TiltSpinner.Value - 1) * size_tilt_angles(2);
                   xippmex('digout', 5, app.digital_output + app.laser_output);
                   pause(0.5)
               end
            end
        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
            if size(app.tilt_angles) == 0
                error('You have not calculated the servo angles yet.')
            elseif size(app.test_angles) == 0
                error("You havn't selected any angles to use");
            else
                app.stop_test = 0;
                app.TestRunningLamp.Color = 'green';
                
                target_num = app.RepeatsSpinner.Value; %the number of repeated target points
                targets = repelem(app.test_angles, target_num); %the test set unrandomized
                targets = targets(randperm(length(targets))); %the targets array randomized

                %run through the target array and record the results
                test_delay = 1; %the minimum delay between tests
                random_delay = 3; %the random amount of time the delay could increase by
                foot_tot_data = []; %empty array to store the recorded foot pedal data
                foot_stamps = []; %empty array to store the clock times of the foot pedal data
                laser_tot_data = []; %empty array to store the recorded laser activation data
                laser_stamps = []; %empty array to store the clock times of the foot pedal data
                start_time = xippmex('time');%start point of test
                for i = 1 : length(targets)
                    if app.stop_test
                        break;
                    end
                    disp(['Test ',num2str(i),' of ',num2str(length(targets))])%display the current test and total test number
                    delay = test_delay + rand(1,1) * random_delay; %the delay amount

                    target = targets(i);
                    xippmex('digout', 5, target); %move the servos to the test positions before the delay

                    %record the foot pedal and laser state delay and record
                    %again
                    [start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps] = recordData(app, start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps);
                    pause(delay);
                    [start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps] = recordData(app, start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps);
                    xippmex('digout', [1, 5], [1, app.digital_output + app.laser_output]); %turn on the laser
                    %xippmex('digout', 1, 1); %turn on the laser state indicator

                    pause(3); %give the subject 3 seconds to react
                    
                    xippmex('digout', [1, 5], [0, app.digital_output]); %turn off the laser
                    %xippmex('digout', 1, 0); %turn off the laser state indicator
                    %record the foot pedal and laser state
                    [start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps] = recordData(app, start_time, foot_tot_data, foot_stamps, laser_tot_data, laser_stamps);
                end
                
                disp('DONE');
                app.TestRunningLamp.Color = 'red';
                
                %create a time stamp for the data set
                DT = clock;
                year = num2str(DT(1));
                if DT(2)<10
                    month = strcat('0',num2str(DT(2)));
                else
                    month = num2str(DT(2));
                end
                if DT(3)<10
                    day = strcat('0',num2str(DT(3)));
                else
                    day = num2str(DT(3));
                end

                foot_pedal_data_name = strcat('Foot_Pedal_Log_', year, month, day, '_', num2str(DT(4)), num2str(DT(5)), num2str(round(DT(6))), '.mat');% data file name
                foot_pedal_wave = [foot_stamps; foot_tot_data];%combine the timing and voltage data from the foot pedal
                save(foot_pedal_data_name, 'foot_pedal_wave');%save foot pedal data

                laser_data_name = strcat('Laser_State_Log_', year, month, day, '_', num2str(DT(4)), num2str(DT(5)), num2str(round(DT(6))), '.mat');% data file name
                laser_state_wave = [laser_stamps; laser_tot_data];%combine the timing and voltage data from the digital putput for the laser state
                save(laser_data_name, 'laser_state_wave');%save laser state data
                
                servo_angle_name = strcat('Servo_Angle_Log_', year, month, day, '_', num2str(DT(4)), num2str(DT(5)), num2str(round(DT(6))), '.mat');% data file name
                save(servo_angle_name, 'targets');
                
            end
        end

        % Button pushed function: StopTestButton
        function StopTestButtonPushed(app, event)
            app.stop_test = 1;
        end

        % Button pushed function: SaveAngleButton
        function SaveAngleButtonPushed(app, event)
            if app.digital_output == 0
                error('You have not calculated angles yet.')
            else
                app.test_tilt_spinner_val = cat(1, app.test_tilt_spinner_val, app.TiltSpinner.Value);
                app.test_angles = cat(1, app.test_angles, app.digital_output);
                app.UITable.Data = [app.test_tilt_spinner_val, app.test_angles]; %update the table of tilt angles
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.current_angle_selected = indices(1, 1);
            xippmex('digout', 5, app.test_angles(indices(1, 1))); % go to the selected angle
            disp(['Tilt of ', num2str(app.tilt_angles(app.test_angles(indices(1, 1))))]);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            new_data = event.NewData;
            if new_data < 0
                error('Value must be above 0.')
            end
            app.test_angles(indices(1, 1)) = new_data;
            app.UITable.Data(indices(1, 1), 1) = "user set";
            app.test_tilt_spinner_val(indices(1, 1)) = "user set";
            disp(app.test_angles);
        end

        % Button pushed function: DeleteAngleButton
        function DeleteAngleButtonPushed(app, event)
            if app.current_angle_selected == -1
                error('Nothing is selected in the table')
            end
            
            app.test_angles(app.current_angle_selected) = [];
            app.test_tilt_spinner_val(app.current_angle_selected) = [];
            app.UITable.Data = [app.test_tilt_spinner_val, app.test_angles]; %update the table of tilt angles
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateServoAnglesButton = uibutton(app.UIFigure, 'push');
            app.CalculateServoAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateServoAnglesButtonPushed, true);
            app.CalculateServoAnglesButton.Text = 'Calculate Servo Angles';
            app.GridWidthmmEditFieldLabel = uilabel(app.UIFigure);
            app.GridWidthmmEditFieldLabel.Text = 'Grid Width (mm)';
            app.GridWidthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GridWidthmmEditField.Value = 300;
            app.GridHeightmmEditFieldLabel = uilabel(app.UIFigure);
            app.GridHeightmmEditFieldLabel.Text = 'Grid Height (mm)';
            app.GridHeightmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GridHeightmmEditField.Value = 150;
            app.DistancefromSurfacemmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancefromSurfacemmEditFieldLabel.Text = 'Distance from Surface (mm)';
            app.DistancefromSurfacemmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancefromSurfacemmEditField.Value = 300;
            app.TiltSpinnerLabel = uilabel(app.UIFigure);
            app.TiltSpinnerLabel.Text = 'Tilt';
            app.TiltSpinner = uispinner(app.UIFigure);
            app.TiltSpinner.Limits = [1 50];
            app.TiltSpinner.ValueChangedFcn = createCallbackFcn(app, @TiltSpinnerValueChanged, true);
            app.TiltSpinner.Value = 1;
            app.LaserToggleButton = uibutton(app.UIFigure, 'state');
            app.LaserToggleButton.ValueChangedFcn = createCallbackFcn(app, @LaserToggleButtonValueChanged, true);
            app.LaserToggleButton.Text = 'Laser Toggle';
            app.LaserSweepButton = uibutton(app.UIFigure, 'push');
            app.LaserSweepButton.ButtonPushedFcn = createCallbackFcn(app, @LaserSweepButtonPushed, true);
            app.LaserSweepButton.Text = 'Laser Sweep';
            app.StartTestButton = uibutton(app.UIFigure, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test';
            app.RepeatsSpinnerLabel = uilabel(app.UIFigure);
            app.RepeatsSpinnerLabel.Text = 'Repeats';
            app.RepeatsSpinner = uispinner(app.UIFigure);
            app.RepeatsSpinner.Limits = [1 50];
            app.RepeatsSpinner.Value = 10;
            app.TestRunningLampLabel = uilabel(app.UIFigure);
            app.TestRunningLampLabel.Text = 'Test Running';
            app.TestRunningLamp = uilamp(app.UIFigure);
            app.StopTestButton = uibutton(app.UIFigure, 'push');
            app.StopTestButton.ButtonPushedFcn = createCallbackFcn(app, @StopTestButtonPushed, true);
            app.StopTestButton.Text = 'Stop Test';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Tilt Angles'; 'Digital Output'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SaveAngleButton = uibutton(app.UIFigure, 'push');
            app.SaveAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAngleButtonPushed, true);
            app.SaveAngleButton.Text = 'Save Angle';
            app.DeleteAngleButton = uibutton(app.UIFigure, 'push');
            app.DeleteAngleButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAngleButtonPushed, true);
            app.DeleteAngleButton.Text = 'Delete Angle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kaoLightTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end